classdef fly_on_the_ball_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        File                            matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SaveBehaviorClassificationMenu  matlab.ui.container.Menu
        SaveDisplayMenu                 matlab.ui.container.Menu
        SaveVideoMenu                   matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        CamerasMenu                     matlab.ui.container.Menu
        VelocityMenu                    matlab.ui.container.Menu
        PathMenu                        matlab.ui.container.Menu
        StimulusMenu                    matlab.ui.container.Menu
        CameraFramesMenu                matlab.ui.container.Menu
        KeypointsMenu                   matlab.ui.container.Menu
        SwingClassificationMenu         matlab.ui.container.Menu
        DisplayPanel                    matlab.ui.container.Panel
        AdjustDisplayButton             matlab.ui.control.Button
        KeypointsCheckBox               matlab.ui.control.CheckBox
        ClassifyBehaviorPanel           matlab.ui.container.Panel
        AnalyzeCheckBox                 matlab.ui.control.CheckBox
        RestingCheckBox                 matlab.ui.control.CheckBox
        WalkingCheckBox                 matlab.ui.control.CheckBox
        FLgroomingCheckBox              matlab.ui.control.CheckBox
        HLgroomingCheckBox              matlab.ui.control.CheckBox
        FlightCheckBox                  matlab.ui.control.CheckBox
        OtherCheckBox                   matlab.ui.control.CheckBox
        StartFrameButton                matlab.ui.control.Button
        EndFrameButton                  matlab.ui.control.Button
        StartFrameEditField             matlab.ui.control.NumericEditField
        EndFrameEditField               matlab.ui.control.NumericEditField
        SetToOneButton                  matlab.ui.control.Button
        SetToZeroButton                 matlab.ui.control.Button
        AdjustKeypointsPanel            matlab.ui.container.Panel
        AdjustKeypointsButton           matlab.ui.control.Button
        KeypointNamesCheckBox           matlab.ui.control.CheckBox
        InteractiveKeypointsCheckBox    matlab.ui.control.CheckBox
        Camera01CheckBox                matlab.ui.control.CheckBox
        Camera02CheckBox                matlab.ui.control.CheckBox
        ExportKeypointsButton           matlab.ui.control.Button
        SingleRwdButton                 matlab.ui.control.Button
        SingleFwdButton                 matlab.ui.control.Button
        FramesSlider                    matlab.ui.control.Slider
        FramesSliderLabel               matlab.ui.control.Label
        FwdButton                       matlab.ui.control.Button
        RwdButton                       matlab.ui.control.Button
        ReplaySpeedSlider               matlab.ui.control.Slider
        ReplayspeedSliderLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        TrialLabel                      matlab.ui.control.Label
        CurrentFrameLabel               matlab.ui.control.Label
        FrameLabel                      matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        CurrentTimeLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        config 
        daq_data
        time
        treadmill_data
        stimulus_data
        camera_data
        classification_data
        deeplabcut
        current_frame
        current_time
        figure_handles
    end
    
    methods (Access = private)
        
        function process_daq_data(app)
            % Downsample DAQ data to reference sampling rate
            [time,camera_data,treadmill_data,stimulus_data] = utils_process_daq_data(app.daq_data,app.config); 
            app.time = time;
            app.camera_data = camera_data;
            app.treadmill_data = treadmill_data;
            app.stimulus_data = stimulus_data;
        end

        function process_treadmill_data(app)
            % Calculates velocities and virtual path
            treadmill_data = utils_process_treadmill_data(app.treadmill_data,app.config);
            app.treadmill_data = treadmill_data;
        end

        function process_deeplabcut_data(app)       
            % Load DeepLabCut data
            camera_data = utils_process_deeplabcut_data(app.camera_data,app.config);
            app.camera_data = camera_data;
        end
           
        function classify_swing(app)
            keypoint_indices = [26,41,56];
            sliding_window = 20; % Frames
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    camera_orientations = app.config.camera.camera_orientations{iCamera};
                    for iKeypoint = 1:numel(keypoint_indices)
                        keypoint_data = app.camera_data.(['data_',camera_name,'_filtered'])(:,keypoint_indices(iKeypoint));

                        reference_keypoint_data = app.camera_data.(['data_',camera_name,'_filtered'])(:,5); % Wing hinge
                        keypoint_data = keypoint_data - reference_keypoint_data;
                        keypoint_data = keypoint_data*-1;
                        %keypoint_data = keypoint_data - keypoint_data(1);

                        swing = utils_classify_swing(keypoint_data,sliding_window);

                        if strcmp(camera_orientations,"left")
                            label = 'L';
                        elseif strcmp(camera_orientations,"right")
                            label = 'R';
                        end
                        app.classification_data.([label,num2str(iKeypoint),'_swing']) = swing;
                    end
                end
            end
        end

        function plot_camera(app)
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,camera_name)
                        if isvalid(app.figure_handles.(camera_name))
                            close(app.figure_handles.(camera_name))
                        end
                    end
                    % Load data
                    vid_data = app.camera_data.(['vid_data_',camera_name])(app.current_frame).cdata;
                    % if app.Flipcamera_02CheckBox.Value && iCamera == 2
                    %         vid_data = fliplr(vid_data);
                    % end

                    % Plot data
                    app.figure_handles.(camera_name) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.(camera_name), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', double(app.config.display.(['pos_',camera_name])), ...
                        'doublebuffer','off');
                end
            end
        end

        function overlay_keypoints(app)
            keypoint_names = app.config.deeplabcut.keypoint_names( ...
                double(app.config.deeplabcut.keypoints_to_overlay));
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    figure(app.figure_handles.(camera_name))
                    hold on
                    for iKeypoint = 1:numel(keypoint_names)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                    end
                    hold off
                end
            end
        end

        function overlay_interactive_keypoints(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                 if app.Camera01CheckBox.Value
                     iCamera = 1; 
                 else
                     iCamera = 2;
                 end
             
                 camera_name = app.config.camera.camera_names{iCamera};
                 if app.config.camera.(['is_',camera_name]) 
                    % Close camera window if open
                    if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                        if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                            close(app.figure_handles.([camera_name,'_set_keypoints']))
                        end
                    end
                    % Plot image
                    vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
                    app.figure_handles.([camera_name,'_set_keypoints']) = figure;
                    imshow(vid_data,'border','tight');
                    set(app.figure_handles.([camera_name,'_set_keypoints']), ...
                        'menubar','none', ...
                        'NumberTitle','off', ...
                        'Name', camera_name, ...
                        'Position', double(app.config.display.(['pos_',camera_name,'_set_keypoints'])), ...
                        'doublebuffer','off');
                    
                    % Plot keypoints 
                    if app.InteractiveKeypointsCheckBox.Value
                        keypoint_names = app.config.deeplabcut.keypoint_names;
                        hold on
                        n_keypoint = 0;
                        for iKeypoint = 1:numel(keypoint_names)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            if app.config.camera.(['is_data_',camera_name])
                                position = app.camera_data.(['data_',camera_name])(app.current_frame,keypoint_indices);
                            else
                                position = [10,10+n_keypoint*10];
                                n_keypoint = n_keypoint+1;
                            end
                            app.deeplabcut.(keypoint_names{iKeypoint}) = images.roi.Point(...
                                gca, ...
                                'Position', position, ...
                                'Label',keypoint_names{iKeypoint}, ...
                                'LabelVisible','hover', ... 
                                'Color', app.config.display.(['color_',keypoint_names{iKeypoint}]));
                        end
                        hold off
                    end
                 end
            end
        end

        function overlay_interactive_keypoint_names(app)
            if (app.Camera01CheckBox.Value ...
                    || app.Camera02CheckBox.Value) 
                if app.Camera01CheckBox.Value
                    iCamera = 1;
                else
                    iCamera = 2;
                end
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,[camera_name,'_set_keypoints'])
                    if isvalid(app.figure_handles.([camera_name,'_set_keypoints']))
                        keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
                        keypoints = keypoints(1:end-1);
                        if app.KeypointNamesCheckBox.Value
                            label_visible = 'on';
                        else
                            label_visible = 'hover';
                        end
                        for iKeypoint = 1:numel(keypoints)
                            keypoints(iKeypoint).LabelVisible = label_visible;
                        end
                    end
                end
            end
        end

        function plot_camera_frames(app)
            app.figure_handles.camera_frames = figure;
            hold on
            plot(app.time, app.camera_data.camera_frames)
            plot([app.current_time, app.current_time], [-5,5], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-.2,4])
            set(app.figure_handles.camera_frames, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name', 'Timestamps', ...
                        'Position', double(app.config.display.('pos_camera_frames')));
        end

        function plot_treadmill_velocity(app)
            if isfield(app.figure_handles,'treadmill_velocity')
                if isvalid(app.figure_handles.treadmill_velocity)
                    close(app.figure_handles.treadmill_velocity)
                end
            end
            app.figure_handles.treadmill_velocity = figure;
            ax1 = subplot(311);
                hold on
                plot(app.time, app.treadmill_data.x_velocity)
                plot([app.current_time, app.current_time], [-30,30], 'k')
                hold off
                ylim([-25,25])
                ylabel('Forward (mm/s)')
                set(gca,'color','none','XTickLabel',[])
            ax2 = subplot(312);
                hold on
                plot(app.time, app.treadmill_data.y_velocity)
                plot([app.current_time, app.current_time], [-30,30], 'k')
                hold off
                ylim([-25,25])
                ylabel('Left side (mm/s)')
                set(gca,'color','none','XTickLabel',[])
            ax3 = subplot(313);
                hold on
                plot(app.time, app.treadmill_data.z_velocity)
                plot([app.current_time, app.current_time], [-500,500], 'k')
                hold off
                ylim([-500,500])
                xlabel('Time (s)')
                ylabel('Left turning (deg/s)')
                set(gca,'color','none')
            set(app.figure_handles.treadmill_velocity, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Treadmill velocity', ...
                'Position', double(app.config.display.('pos_treadmill_velocity')));
            linkaxes([ax1 ax2 ax3],'x')
        end

        function plot_treadmill_path(app)
            app.figure_handles.treadmill_path = figure;
            hold on
            plot(app.treadmill_data.y_path, app.treadmill_data.x_path)
            triangle_x = [0, 0.15, -0.15];
            triangle_y = [0.3, -0.3, -0.3];
            % Set position
            position = [app.treadmill_data.y_path(app.current_frame), ...
                        app.treadmill_data.x_path(app.current_frame)]; 
            h_patch = patch(triangle_x + position(1), ...
                triangle_y + position(2), ...
                [1,1,1], ...
                'EdgeColor', [0,0,0],...
                'LineWidth', 1,...
                'FaceColor', [0,0,0]);
            % Set rotation
            rotate(h_patch, ...
                [0 0 1], ...
                rad2deg(app.treadmill_data.heading(app.current_frame)), ...
                [position 0])

            hold off
            xlabel('Y (mm)')
            ylabel('X (mm)')
            set(gca,'color','none','XDir','reverse')
            axis equal
            grid on
            set(app.figure_handles.treadmill_path, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Treadmill path', ...
                'Position', double(app.config.display.('pos_treadmill_path')));
        end
        
        function plot_keypoints(app)
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_data_',camera_name])
                    % Close figure if open
                    if isfield(app.figure_handles,[camera_name,'_keypoints'])
                        if isvalid(app.figure_handles.([camera_name,'_keypoints']))
                            close(app.figure_handles.([camera_name,'_keypoints']))
                        end
                    end

                    % Get keypoint data of interest
                    keypoints_to_plot = double(app.config.deeplabcut.keypoints_to_plot);
                    keypoint_indices = app.config.deeplabcut.keypoint_indices;
                    keypoint_indices_to_plot = [];
                    for iKeypoint = 1:numel(keypoints_to_plot)
                        keypoint_indices_to_plot = [keypoint_indices_to_plot, ...
                            double(keypoint_indices{keypoints_to_plot(iKeypoint)})];
                    end
                    keypoint_data = app.camera_data.(['data_',camera_name,'_filtered']) ...
                        (:,keypoint_indices_to_plot);
        
                    % Subtract reference keypoint (wing hinge)
                    reference_keypoint_data = app.camera_data.(['data_',camera_name,'_filtered']) ...
                        (:,[4,5]);
                    keypoint_data(:,1:2:end) = keypoint_data(:,1:2:end)-reference_keypoint_data(:,1);
                    keypoint_data(:,2:2:end) = keypoint_data(:,2:2:end)-reference_keypoint_data(:,2);
                    
                    app.figure_handles.([camera_name,'_keypoints']) = figure;
                    keypoint_names = app.config.deeplabcut.keypoint_names;
                    ax1 = subplot(211); 
                        hold on
                        for iKeypoint = 1:numel(keypoints_to_plot)
                            plot(app.time, keypoint_data(:,iKeypoint*2-1), ...
                                'Color', app.config.display.(['color_',keypoint_names{keypoints_to_plot(iKeypoint)}])) 
                        end
                        plot([app.current_time, app.current_time], [-500,500], 'k')
                        hold off
                        xlabel('Time (s)')
                        ylabel('X coordinate (px)')
                        set(gca,'color','none','YDir','reverse')
                        ylim([-500,500])
                    ax2 = subplot(212); 
                        hold on
                        for iKeypoint = 1:numel(keypoints_to_plot)
                            plot(app.time, keypoint_data(:,iKeypoint*2), ...
                                'Color', app.config.display.(['color_',keypoint_names{keypoints_to_plot(iKeypoint)}])) 
                        end
                        plot([app.current_time, app.current_time], [-500,500], 'k')
                        hold off
                        xlabel('Time (s)')
                        ylabel('Y coordinate (px)')
                        set(gca,'color','none','YDir','reverse')
                        ylim([-500,500])
                    set(gcf, ...
                        'menubar','none', ...
                        'toolbar','figure',...
                        'NumberTitle','off', ...
                        'Name',[camera_name,' keypoints'], ...
                        'Position', double(app.config.display.(['pos_',camera_name,'_keypoints'])));
                    linkaxes([ax1 ax2],'x')
                end
            end
        end

        function plot_stimulus(app)
            % Close figure if open
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    close(app.figure_handles.stimulus)
                end
            end

            app.figure_handles.stimulus = figure;
            hold on
            plot(app.time, app.stimulus_data)
            plot([app.current_time, app.current_time], [-11,11], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Voltage (V)')
            set(gca,'color','none')
            ylim([-10.5,10.5])
            set(app.figure_handles.stimulus, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Stimulus', ...
                'Position', double(app.config.display.('pos_stimulus')));
        end

        function plot_behavior_classification(app)
            % Close figure if open
            if isfield(app.figure_handles,'behavior_classification')
                if isvalid(app.figure_handles.behavior_classification)
                    close(app.figure_handles.behavior_classification)
                end
            end

            % Flip classifications for figure
            classifications = fliplr({'Analyze','Resting','Walking','FL grooming',' HL grooming', 'Flight', 'Other'});
            classifications_data = fliplr(app.classification_data.behavior);
            app.figure_handles.behavior_classification = figure;
            hold on
            for iClassification = 1:numel(classifications)
                y_offset = iClassification*2;
                plot(app.time, classifications_data(:,iClassification)+y_offset, ...
                    'Color', [0 0.4470 0.7410])
            end
            plot([app.current_time, app.current_time], [1,numel(classifications)*2+2], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Behavior')
            set(gca,'color','none','YTick',2:2:numel(classifications)*2,'YTickLabel',classifications,'TickLabelInterpreter','none')
            ylim([1,numel(classifications)*2+2])
            set(app.figure_handles.behavior_classification, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Behavior classification', ...
                'Position', double(app.config.display.('pos_behavior_classification')));
        end

        function plot_swing_classification(app)
            % Close figure if open
            if isfield(app.figure_handles,'swing_classification')
                if isvalid(app.figure_handles.swing_classification)
                    close(app.figure_handles.swing_classification)
                end
            end

            classifications = {'L1_swing','L2_swing','L3_swing','R1_swing','R2_swing','R3_swing'};
            colors = [app.config.display.color_I1E; app.config.display.color_I2E; app.config.display.color_I3E; ...
                app.config.display.color_I1E; app.config.display.color_I2E; app.config.display.color_I3E];
            app.figure_handles.swing_classification = figure;
            hold on
            for iClassification = 1:numel(classifications)
                y_offset = iClassification*2;
                plot(app.time(1:numel(app.classification_data.L1_swing)), ...
                    app.classification_data.(classifications{iClassification})+y_offset, ...
                    'Color', colors(iClassification,:))
            end
            plot([app.current_time, app.current_time], [1,numel(classifications)*2+2], 'k')
            hold off
            xlabel('Time (s)')
            ylabel('Swing')
            set(gca,'color','none','YTick',2:2:numel(classifications)*2,'YTickLabel',classifications,'TickLabelInterpreter','none')
            ylim([1,numel(classifications)*2+2])
            set(app.figure_handles.swing_classification, ...
                'menubar','none', ...
                'toolbar','figure',...
                'NumberTitle','off', ...
                'Name', 'Swing classification', ...
                'Position', double(app.config.display.('pos_swing_classification')));
        end

        function update_time(app)
            app.current_time = app.time(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);
        end

        function update_figures(app)
            % Update video plots 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,camera_name) ...
                        && isvalid(app.figure_handles.(camera_name))
                    % Update CData
                    app.figure_handles.(camera_name).CurrentAxes.Children(end).CData = ...
                        app.camera_data.(['vid_data_',camera_name])(app.current_frame).cdata;
                    % Update keypoints
                    if app.config.camera.(['is_data_',camera_name])
                        keypoints = app.figure_handles.(camera_name).Children.Children;
                        keypoints = keypoints(1:end-1); % Exclude video image
                        keypoints = flipud(keypoints); % Invert order

                        figure(app.figure_handles.(camera_name))
                        for iKeypoint = 1:numel(keypoints)
                            keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                            keypoints(iKeypoint).XData = app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(1));
                            keypoints(iKeypoint).YData = app.camera_data.(['data_',camera_name,'_filtered'])(app.current_frame,keypoint_indices(2));
                        end
                    end
                end
            end

            % Update camera frames plot
            if isfield(app.figure_handles,'camera_frames')
                if isvalid(app.figure_handles.camera_frames)
                    app.figure_handles.camera_frames.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update treadmill velocity plot
            if isfield(app.figure_handles,'treadmill_velocity')
                if isvalid(app.figure_handles.treadmill_velocity)
                    h_subplots = app.figure_handles.treadmill_velocity.Children;
                    for iSubplot = 1:numel(h_subplots)
                        h_subplots(iSubplot).Children(1).XData = ...
                            [app.current_time, app.current_time];
                    end
                end
            end

            % Update treadmill path plot
            if isfield(app.figure_handles,'treadmill_path')
                if isvalid(app.figure_handles.treadmill_path)
                    % Update position
                    triangle_x = [0, 0.15, -0.15];
                    triangle_y = [0.3, -0.3, -0.3];
                    position = [app.treadmill_data.y_path(app.current_frame), ...
                        app.treadmill_data.x_path(app.current_frame)];
                    h_patch = app.figure_handles.treadmill_path.Children.Children(1);
                    h_patch.XData = triangle_x + position(1); 
                    h_patch.YData = triangle_y + position(2);
                    % Update rotation
                    rotate(h_patch, ...
                        [0 0 1], ...
                        rad2deg(app.treadmill_data.heading(app.current_frame)), ...
                        [position 0])
                end
            end

            % Update keypoints plot
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if isfield(app.figure_handles,[camera_name,'_keypoints'])
                    if isvalid(app.figure_handles.([camera_name,'_keypoints']))
                        app.figure_handles.([camera_name,'_keypoints']).Children(1).Children(1).XData = ...
                            [app.current_time, app.current_time];
                        app.figure_handles.([camera_name,'_keypoints']).Children(2).Children(1).XData = ...
                            [app.current_time, app.current_time];
                    end
                end
            end
            
            % Update stimulus plot
            if isfield(app.figure_handles,'stimulus')
                if isvalid(app.figure_handles.stimulus)
                    app.figure_handles.stimulus.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update behavior classification plot
            if isfield(app.figure_handles,'behavior_classification')
                if isvalid(app.figure_handles.behavior_classification)
                    app.figure_handles.behavior_classification.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end

            % Update swing classification plot
            if isfield(app.figure_handles,'swing_classification')
                if isvalid(app.figure_handles.swing_classification)
                    app.figure_handles.swing_classification.Children.Children(1).XData = ...
                        [app.current_time, app.current_time];
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrameLabel.Text = "";
            app.TimeLabel.Text = "";
            app.TrialLabel.Text = "";
            app.KeypointsCheckBox.Value = true;
            app.StartFrameEditField.Value = 0;
            app.EndFrameEditField.Value = 0;

            % Reset private properties except for config
            mc = ?fly_on_the_ball_analysis_app;
            property_list = mc.PropertyList;
            for iProperty = 1:numel(property_list)
                if all(strcmp(property_list(iProperty).GetAccess,'private')) ...
                        && ~strcmp(property_list(iProperty).Name,'config')
                    app.(property_list(iProperty).Name) = [];
                end
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            startupFcn(app)
            
            % If config already exists, get previous experimental folder
            previous_folder = [];
            if ~isempty(app.config) 
                if isfield(app.config,'experiment')
                    previous_folder = [app.config.dir.data, app.config.experiment];
                end
            end
            
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            app.config = toml.map_to_struct(config);

            % Set directory
            app.config.dir.data = app.config.dir.daq_data;
                
            if isempty(previous_folder)
                [app.config.file_name,~] = uigetfile(...
                    {'*.*'},...
                    'Select mat file',...
                    app.config.dir.data);
                if app.config.file_name == 0
                    return
                end
            else
                [app.config.file_name,~] = uigetfile(...
                    {'*.*'},...
                    'Select mat file',...
                    previous_folder);
                if app.config.file_name == 0
                    return
                end
            end

            app.config.trial_name = app.config.file_name(1:end-4); 
            app.config.experiment = app.config.file_name(1:end-8);
            
            clc
            disp(['Loading ',app.config.trial_name,'...'])

            % Use the camera sampling rate as the reference sampling rate
            app.config.reference_sampling_rate = app.config.camera.sampling_rate;
            
            % Load DAQ data
            load([app.config.dir.data, ...
                app.config.experiment,'/', ...
                app.config.file_name]);
            app.daq_data = trial_data;

            % Process DAQ data
            process_daq_data(app)

            % Check if videos are available
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.config.camera.(['is_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                        app.config.experiment, ...
                        app.config.camera.folder_videos, ...
                        app.config.trial_name,'_', ...
                        camera_name,'.mp4'], ...
                        'file') == 2
            
                    app.config.camera.(['is_',camera_name]) = true;
                end
                clearvars camera_name
            end

            % Check if leg tracking data are available 
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                app.config.camera.(['is_data_',camera_name]) = false;
                if exist( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name, '_',...
                         camera_name, ...
                         app.config.deeplabcut.model_name,'.h5'],...
                        'file') == 2
                    
                    app.config.camera.(['is_data_',camera_name]) = true;
                end
            end

            % Initialize videos 
            % 
            % Note: For mp4, loading video data frame by frame in the
            % update function above rather than here would be much slower,
            % particularly when playing the video backwards. This is
            % because mp4 files are not indexed by frame number, but by
            % frame time, and they are potentially variable-rate. When
            % moving forward, the internal code needs to keep a buffer of
            % the current group of frames. When moving backward, the
            % internal code discards the buffer and moves forward from the
            % beginning, which takes time. See also
            % https://www.mathworks.com/matlabcentral/answers/704167-videoreader-reads-frames-significantly-faster-forward-than-backwards.
            for iCamera = 1:numel(app.config.camera.camera_names)
                camera_name = app.config.camera.camera_names{iCamera};
                if app.config.camera.(['is_',camera_name])
                    app.camera_data.(['vid_obj_',app.config.camera.camera_names{iCamera}]) = VideoReader( ...
                        [app.config.dir.data, ...
                         app.config.experiment, ...
                         app.config.camera.folder_videos, ...
                         app.config.trial_name,'_', ...
                         camera_name,'.mp4']);
                    
                    % Load video data
                    % Note: readFrame() is faster than read()
                    disp(['Loading ',camera_name,' data...'])
                    vid_obj = app.camera_data.(['vid_obj_',app.config.camera.camera_names{iCamera}]);
                    app.camera_data.(['vid_data_',camera_name]) = ...
                        struct('cdata', zeros(vid_obj.Height,vid_obj.Width,3,'uint8'), ...
                        'colormap', []);
                    k = 1;
                    while hasFrame(vid_obj)
                        app.camera_data.(['vid_data_',camera_name])(k).cdata = readFrame(vid_obj);
                        k = k+1;
                    end
                    disp('Done!')         
                end
                clearvars camera_name
            end
            
            app.current_frame = 1;
            app.current_time = 0;
            % Because the cameras continue recording for a few frames after
            % the end of the session, calculate the last available frame
            % based on the duration of the session.
            app.config.last_frame = length(app.time); 
            app.FramesSlider.Limits = [1, app.config.last_frame];
            app.FramesSlider.Value = 1;
            app.ReplaySpeedSlider.Limits = [1, app.config.last_frame/double(app.config.reference_sampling_rate)*3];
            app.TrialLabel.Text = app.config.trial_name;
            app.FrameLabel.Text = string(app.current_frame);
            app.TimeLabel.Text = string(app.current_time);

            is_camera_data = app.config.camera.(['is_data_',app.config.camera.camera_names{1}]);


            % Check if behavior classifaction is available
            path_to_csv = [app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_behavior_classification.csv'];
            if exist(path_to_csv, 'file') == 2
                app.classification_data.behavior = csvread(path_to_csv);
            else
                % Initialize classification data
                % Analyze, resting, walking, FL grooming, HL grooming, flight, other 
                app.classification_data.behavior = zeros(app.config.last_frame,7);
                app.classification_data.behavior(:,1) = 1;
            end
            
            % Process data
            process_treadmill_data(app)
            if is_camera_data
                process_deeplabcut_data(app)
                % classify_swing(app)
            end

            % Plot data
            plot_camera(app) 
            if app.KeypointsCheckBox.Value
                overlay_keypoints(app)
            end
            plot_treadmill_velocity(app)
            plot_treadmill_path(app)
            plot_behavior_classification(app)
            if is_camera_data
                plot_keypoints(app)
                % plot_swing_classification(app)
            end
            plot_stimulus(app)
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            close all
        end

        % Menu selected function: SaveBehaviorClassificationMenu
        function SaveBehaviorClassificationMenuSelected(app, event)
            save_path = strcat( ...
                app.config.dir.data, ...
                app.config.experiment, ...
                app.config.dir.classification_data, ...
                app.config.trial_name, ...
                '_behavior_classification.csv');
            classification_data = app.classification_data.behavior;
            writematrix(classification_data, save_path)
            disp(['Behavior classification saved as ',convertStringsToChars(save_path)])
        end

        % Menu selected function: SaveDisplayMenu
        function SaveDisplayMenuSelected(app, event)
            % Load configuration file
            path_config = 'config.toml';
            config = toml.read(path_config);
            config = toml.map_to_struct(config);

            figure_names = fieldnames(app.figure_handles);
            for iFigure = 1:numel(figure_names)
                if isvalid(app.figure_handles.(figure_names{iFigure}))
                    pos = app.figure_handles.(figure_names{iFigure}).Position;
                    config.display.(['pos_',(figure_names{iFigure})]) = int64(pos);
                end
            end
            toml.write('config.toml',config)
            disp('Display options saved to config.toml')
        end

        % Menu selected function: SaveVideoMenu
        function SaveVideoMenuSelected(app, event)
            % To do: Generalize
            save_dir = 'C:/Users/Chris/Desktop/2024-06-03_A02_002_510-880_1x.mp4';
            start_frame = 510;
            end_frame = 880; 
            sampling_rate = 30;
            replay_speed = 1; %0.1; %0.1; 
            iCamera = 1;

            interval_frame = app.config.reference_sampling_rate/(sampling_rate/replay_speed);
            save_frames = start_frame:interval_frame:end_frame;
             
            stimulus_frames = app.stimulus_data;
            time_frames = app.time;
            % Express time relative to stimulus onset
            time_frames = time_frames - time_frames(find(stimulus_frames>-8,1,'first'));
            
            f = figure;
            n_frame = 0;
            for iFrame = save_frames
                n_frame = n_frame + 1;
                figure(f), clf

                % Plot video
                camera_name = app.config.camera.camera_names{iCamera};
                vid_data = read(app.camera_data.(['vid_obj_',camera_name]),iFrame);
                imshow(vid_data,'border','tight');
                set(f, ...
                    'menubar','none', ...
                    'NumberTitle','off', ...
                    'Name', camera_name, ...
                    'Position', double(app.config.display.(['pos_',camera_name])));

                % text(850,60,'slowed 10x', ...
                %     'FontName', 'Arial', 'FontSize', 18, ...
                %     'Color', [1,1,1], ...
                %     'HorizontalAlignment', 'right')
                
                % Add time
                time_str = num2str(time_frames(iFrame),'%3.2f');
                text(850,60,[time_str,' s'], ...
                    'FontName', 'Arial', 'FontSize', 16, ...
                    'Color', [1,1,1], ...
                    'HorizontalAlignment', 'right')

                % Overlay stimulus indicator
                if stimulus_frames(iFrame) > -8 
                    hold on
                    rectangle('Position',[100,40,40,40],'FaceColor','w')
                end

                if app.config.camera.(['is_',camera_name])
                    labels = app.config.deeplabcut.keypoint_names; % To do: Update
                    labels = labels(1:end-8);
                    figure(f)
                    hold on
                    for iKeypoint = 1:numel(labels)
                        keypoint_indices = double(app.config.deeplabcut.keypoint_indices{iKeypoint});
                        plot(...
                            app.camera_data.(['data_',camera_name,'_filtered'])(iFrame,keypoint_indices(1)), ...
                            app.camera_data.(['data_',camera_name,'_filtered'])(iFrame,keypoint_indices(2)), ...
                            'o', ...
                            'Color', app.config.display.(['color_',labels{iKeypoint}]), ...
                            'MarkerFaceColor', app.config.display.(['color_',labels{iKeypoint}]));
                    end
                    hold off
                end
                
                F(n_frame) = getframe(gcf);
            end
            close(f)

            % Create a video writer object
            writer_obj = VideoWriter(save_dir,'MPEG-4');
            writer_obj.FrameRate = sampling_rate;
            open(writer_obj);
            % Write frames to video
            for iFrame = 1:length(F)
                % Convert image to frame
                frame = F(iFrame);
                writeVideo(writer_obj, frame);
            end
            clearvars F
            % Close the video writer object
            close(writer_obj);
            disp(['Video saved as ',save_dir])
        end

        % Menu selected function: CamerasMenu
        function CamerasMenuSelected(app, event)
            plot_camera(app)
        end

        % Menu selected function: VelocityMenu
        function VelocityMenuSelected(app, event)
            plot_treadmill_velocity(app)
        end

        % Menu selected function: PathMenu
        function PathMenuSelected(app, event)
            plot_treadmill_path(app)
        end

        % Menu selected function: StimulusMenu
        function StimulusMenuSelected(app, event)
            plot_stimulus(app)
        end

        % Menu selected function: CameraFramesMenu
        function CameraFramesMenuSelected(app, event)
            plot_camera_frames(app)
        end

        % Menu selected function: KeypointsMenu
        function KeypointsMenuSelected(app, event)
            plot_keypoints(app)
        end

        % Menu selected function: SwingClassificationMenu
        function SwingClassificationMenuSelected(app, event)
            plot_swing_classification(app)
        end

        % Button pushed function: FwdButton
        function FwdButtonPushed(app, event)
            if strcmp(app.FwdButton.Text,'Fwd')
                app.FwdButton.Text = 'Stop';
                app.RwdButton.Text = 'Rwd';
            else
                app.FwdButton.Text = 'Fwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplaySpeedSlider.Value); 
            while app.current_frame <= app.config.last_frame - replay_frames
                if strcmp(app.FwdButton.Text,'Fwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) + replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame); 
                update_time(app)
                update_figures(app)  
                drawnow
            end
            if app.current_frame > app.config.last_frame - replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                app.FwdButton.Text = 'Fwd';
                update_time(app)
                update_figures(app)  
                drawnow
            end
        end

        % Button pushed function: RwdButton
        function RwdButtonPushed(app, event)
            if strcmp(app.RwdButton.Text,'Rwd')
                app.RwdButton.Text = 'Stop';
            else
                app.RwdButton.Text = 'Rwd';
            end

            % Loop through frames
            replay_frames = round(app.ReplaySpeedSlider.Value); 
            while app.current_frame >= 1 + replay_frames
                if strcmp(app.RwdButton.Text,'Rwd')
                    break;
                end
                app.current_frame = round(app.FramesSlider.Value) - replay_frames;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                update_time(app)
                update_figures(app)
                drawnow
            end
            if app.current_frame < 1 + replay_frames
                app.current_frame = 1;
                app.FramesSlider.Value = app.current_frame;
                app.FrameLabel.Text = string(app.current_frame);
                app.RwdButton.Text = 'Rwd';
                update_time(app)
                update_figures(app)  
                drawnow
            end
        end

        % Button pushed function: SingleFwdButton
        function SingleFwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value+1;
            if value>app.config.last_frame
                value = app.config.last_frame;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);

            update_time(app)
            update_figures(app)
            drawnow
        end

        % Button pushed function: SingleRwdButton
        function SingleRwdButtonPushed(app, event)
            value = round(app.FramesSlider.Value);
            value = value-1;
            if value<1
                value = 1;
            end
            app.current_frame = value;
            app.FramesSlider.Value = value;
            app.FrameLabel.Text = string(value);
            
            update_time(app)
            update_figures(app)
            drawnow
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            value = round(app.FramesSlider.Value);
            app.current_frame = value;
            app.FrameLabel.Text = string(value);
                
            update_time(app)
            update_figures(app)
            drawnow
        end

        % Button pushed function: AdjustDisplayButton
        function AdjustDisplayButtonPushed(app, event)
            plot_camera(app)
            if app.KeypointsCheckBox.Value
                overlay_keypoints(app)
            end
        end

        % Value changed function: AnalyzeCheckBox
        function AnalyzeCheckBoxChanged(app, event)
            value = app.AnalyzeCheckBox.Value;
            if value
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: RestingCheckBox
        function RestingCheckBoxChanged(app, event)
            value = app.RestingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: WalkingCheckBox
        function WalkingCheckBoxChanged(app, event)
            value = app.WalkingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: FLgroomingCheckBox
        function FLgroomingCheckBoxChanged(app, event)
            value = app.FLgroomingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: HLgroomingCheckBox
        function HLgroomingCheckBoxChanged(app, event)
            value = app.HLgroomingCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: FlightCheckBox
        function FlightCheckBoxChanged(app, event)
            value = app.FlightCheckBox.Value;
            if value
                app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.OtherCheckBox.Value = false;
            end
        end

        % Value changed function: OtherCheckBox
        function OtherCheckBoxChanged(app, event)
            value = app.OtherCheckBox.Value;
             if value
                 app.AnalyzeCheckBox.Value = false;
                app.RestingCheckBox.Value = false;
                app.WalkingCheckBox.Value = false;
                app.FLgroomingCheckBox.Value = false;
                app.HLgroomingCheckBox.Value = false;
                app.FlightCheckBox.Value = false;
            end
        end

        % Button pushed function: StartFrameButton
        function StartFrameButtonPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.StartFrameEditField.Value = dcinfo.DataIndex;
            else            
                app.StartFrameEditField.Value = app.current_frame;
            end
        end

        % Button pushed function: EndFrameButton
        function EndFrameButtonPushed(app, event)
            % Get cursor position
            dcm_obj = datacursormode(app.figure_handles.behavior_classification);
            dcinfo = getCursorInfo(dcm_obj);
            if ~isempty(dcinfo)
                app.EndFrameEditField.Value = dcinfo.DataIndex;
            else            
                app.EndFrameEditField.Value = app.current_frame;
            end
        end

        % Button pushed function: SetToOneButton
        function SetToOneButtonPushed(app, event)
            col = [];
            if app.AnalyzeCheckBox.Value 
                col = 1;
            elseif app.RestingCheckBox.Value
                col = 2;
            elseif app.WalkingCheckBox.Value
                col = 3;
            elseif app.FLgroomingCheckBox.Value
                col = 4;   
            elseif app.HLgroomingCheckBox.Value
                col = 5; 
            elseif app.FlightCheckBox.Value
                col = 6; 
            elseif app.OtherCheckBox.Value
                col = 7; 
            end

            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditField.Value) ...
                    && ~isempty(app.EndFrameEditField.Value)
                app.classification_data.behavior(app.StartFrameEditField.Value:app.EndFrameEditField.Value, col) = 1;
                plot_behavior_classification(app)
            end
        end

        % Button pushed function: SetToZeroButton
        function SetToZeroButtonPushed(app, event)
            col = [];
            if app.AnalyzeCheckBox.Value 
                col = 1;
            elseif app.RestingCheckBox.Value
                col = 2;
            elseif app.WalkingCheckBox.Value
                col = 3;
            elseif app.FLgroomingCheckBox.Value
                col = 4;   
            elseif app.HLgroomingCheckBox.Value
                col = 5; 
            elseif app.FlightCheckBox.Value
                col = 6; 
            elseif app.OtherCheckBox.Value
                col = 7; 
            end

            if ~isempty(col) ...
                    && ~isempty(app.StartFrameEditField.Value) ...
                    && ~isempty(app.EndFrameEditField.Value)
                app.classification_data.behavior(app.StartFrameEditField.Value:app.EndFrameEditField.Value, col) = 0;
                plot_behavior_classification(app)
            end
        end

        % Value changed function: Camera01CheckBox
        function Camera01CheckBoxSelected(app, event)
            value = app.Camera01CheckBox.Value;
            if value 
                app.Camera02CheckBox.Value = false;
            end
        end

        % Value changed function: Camera02CheckBox
        function Camera02CheckBoxSelected(app, event)
            value = app.Camera02CheckBox.Value;
            if value 
                app.Camera01CheckBox.Value = false;
            end
        end

        % Value changed function: KeypointNamesCheckBox
        function ShowKeypointNamesChanged(app, event)
            overlay_interactive_keypoint_names(app)
        end

        % Button pushed function: AdjustKeypointsButton
        function AdjustKeypointsButtonPushed(app, event)
            overlay_interactive_keypoints(app)
            if app.KeypointNamesCheckBox.Value
                overlay_interactive_keypoint_names(app)
            end
        end

        % Button pushed function: ExportKeypointsButton
        function ExportKeypointsButtonPushed(app, event)
            x_ignore = 50;
            if app.Camera01CheckBox.Value
                iCamera = 1; 
            else
                iCamera = 2;
            end
            camera_name = app.config.camera.camera_names{iCamera};
            keypoints = app.figure_handles.([camera_name,'_set_keypoints']).Children.Children;
            keypoints = keypoints(1:end-1); % Exclude video image
            keypoints = flipud(keypoints); % Invert order
            png_name = ['img',sprintf('%05d',str2double(app.FrameLabel.Text)),'.png'];
            labeled_data = [];
            col = 1;
            for iKeypoint = 1:numel(keypoints)
                if keypoints(iKeypoint).Position(1) > x_ignore
                    labeled_data(col) = keypoints(iKeypoint).Position(1); % X coordinate
                    labeled_data(col+1) = keypoints(iKeypoint).Position(2); % Y coordinate
                else               
                    labeled_data(col) = missing;
                    labeled_data(col+1) = missing;
                end
                col = col+2;
            end
            % Save mat-file 
            % Python starts indexing at 0, Matlab at 1. Keypoint
            % coordinates are corrected later when writing csv file for
            % DeepLabCut.
            path_to_folder = [app.config.dir.deeplabcut_training_data,app.config.trial_name,'/'];
            if ~exist(path_to_folder, 'dir')
                mkdir(path_to_folder)
            end
            save([path_to_folder, app.config.trial_name,'_',camera_name,'_',png_name(1:end-4),'.mat'],'labeled_data')
            % Plot video frame without labels and save as png
            vid_data = read(app.camera_data.(['vid_obj_',camera_name]),app.current_frame);
            figure
            imshow(vid_data,'border','tight');
            set(gcf, ...
                'menubar','none', ...
                'NumberTitle','off', ...
                'Name', camera_name, ...
                'Position', [200,200,size(vid_data,2),size(vid_data,1)]);
            export_fig([path_to_folder, app.config.trial_name,'_',camera_name,'_',png_name],gcf) % saveas() changes figure size 
            close(gcf)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.OpenMenu = uimenu(app.File);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.File);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SaveBehaviorClassificationMenu = uimenu(app.File);
            app.SaveBehaviorClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveBehaviorClassificationMenuSelected, true);
            app.SaveBehaviorClassificationMenu.Text = 'Save Behavior Classification';
            app.SaveDisplayMenu = uimenu(app.File);
            app.SaveDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDisplayMenuSelected, true);
            app.SaveDisplayMenu.Text = 'Save Display';
            app.SaveVideoMenu = uimenu(app.File);
            app.SaveVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveVideoMenuSelected, true);
            app.SaveVideoMenu.Text = 'Save Video';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.CamerasMenu = uimenu(app.ViewMenu);
            app.CamerasMenu.MenuSelectedFcn = createCallbackFcn(app, @CamerasMenuSelected, true);
            app.CamerasMenu.Text = 'Cameras';
            app.VelocityMenu = uimenu(app.ViewMenu);
            app.VelocityMenu.MenuSelectedFcn = createCallbackFcn(app, @VelocityMenuSelected, true);
            app.VelocityMenu.Text = 'Velocity';
            app.PathMenu = uimenu(app.ViewMenu);
            app.PathMenu.MenuSelectedFcn = createCallbackFcn(app, @PathMenuSelected, true);
            app.PathMenu.Text = 'Path';
            app.StimulusMenu = uimenu(app.ViewMenu);
            app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @StimulusMenuSelected, true);
            app.StimulusMenu.Text = 'Stimulus';
            app.CameraFramesMenu = uimenu(app.ViewMenu);
            app.CameraFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @CameraFramesMenuSelected, true);
            app.CameraFramesMenu.Text = 'Camera Frames';
            app.KeypointsMenu = uimenu(app.ViewMenu);
            app.KeypointsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeypointsMenuSelected, true);
            app.KeypointsMenu.Text = 'Keypoints';
            app.SwingClassificationMenu = uimenu(app.ViewMenu);
            app.SwingClassificationMenu.MenuSelectedFcn = createCallbackFcn(app, @SwingClassificationMenuSelected, true);
            app.SwingClassificationMenu.Text = 'Swing Classification';
            app.Panel = uipanel(app.UIFigure);
            app.CurrentTimeLabel = uilabel(app.Panel);
            app.CurrentTimeLabel.Text = 'Current time';
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.FrameLabel = uilabel(app.Panel);
            app.FrameLabel.Text = 'Frame';
            app.CurrentFrameLabel = uilabel(app.Panel);
            app.CurrentFrameLabel.Text = 'Current frame';
            app.TrialLabel = uilabel(app.Panel);
            app.TrialLabel.Text = 'Trial';
            app.ReplayspeedSliderLabel = uilabel(app.UIFigure);
            app.ReplayspeedSliderLabel.Text = 'Replay speed';
            app.ReplaySpeedSlider = uislider(app.UIFigure);
            app.ReplaySpeedSlider.Limits = [1 300];
            app.ReplaySpeedSlider.MajorTicks = [];
            app.ReplaySpeedSlider.MajorTickLabels = {''};
            app.ReplaySpeedSlider.MinorTicks = [];
            app.ReplaySpeedSlider.Value = 1;
            app.RwdButton = uibutton(app.UIFigure, 'push');
            app.RwdButton.ButtonPushedFcn = createCallbackFcn(app, @RwdButtonPushed, true);
            app.RwdButton.Text = 'Rwd';
            app.FwdButton = uibutton(app.UIFigure, 'push');
            app.FwdButton.ButtonPushedFcn = createCallbackFcn(app, @FwdButtonPushed, true);
            app.FwdButton.Text = 'Fwd';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.MinorTicks = [];
            app.SingleFwdButton = uibutton(app.UIFigure, 'push');
            app.SingleFwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleFwdButtonPushed, true);
            app.SingleFwdButton.Text = '>';
            app.SingleRwdButton = uibutton(app.UIFigure, 'push');
            app.SingleRwdButton.ButtonPushedFcn = createCallbackFcn(app, @SingleRwdButtonPushed, true);
            app.SingleRwdButton.Text = '<';
            app.AdjustKeypointsPanel = uipanel(app.UIFigure);
            app.AdjustKeypointsPanel.Title = 'Adjust keypoints ';
            app.ExportKeypointsButton = uibutton(app.AdjustKeypointsPanel, 'push');
            app.ExportKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportKeypointsButtonPushed, true);
            app.ExportKeypointsButton.Text = 'Export';
            app.Camera02CheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.Camera02CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera02CheckBoxSelected, true);
            app.Camera02CheckBox.Text = 'Camera 02';
            app.Camera01CheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.Camera01CheckBox.ValueChangedFcn = createCallbackFcn(app, @Camera01CheckBoxSelected, true);
            app.Camera01CheckBox.Text = 'Camera 01';
            app.InteractiveKeypointsCheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.InteractiveKeypointsCheckBox.Text = 'Keypoints';
            app.KeypointNamesCheckBox = uicheckbox(app.AdjustKeypointsPanel);
            app.KeypointNamesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowKeypointNamesChanged, true);
            app.KeypointNamesCheckBox.Text = 'Keypoint names';
            app.AdjustKeypointsButton = uibutton(app.AdjustKeypointsPanel, 'push');
            app.AdjustKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustKeypointsButtonPushed, true);
            app.AdjustKeypointsButton.Text = 'Adjust';
            app.ClassifyBehaviorPanel = uipanel(app.UIFigure);
            app.ClassifyBehaviorPanel.Title = 'Classify behavior';
            app.SetToZeroButton = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.SetToZeroButton.ButtonPushedFcn = createCallbackFcn(app, @SetToZeroButtonPushed, true);
            app.SetToZeroButton.Text = 'Zero';
            app.SetToOneButton = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.SetToOneButton.ButtonPushedFcn = createCallbackFcn(app, @SetToOneButtonPushed, true);
            app.SetToOneButton.Text = 'One';
            app.EndFrameEditField = uieditfield(app.ClassifyBehaviorPanel, 'numeric');
            app.StartFrameEditField = uieditfield(app.ClassifyBehaviorPanel, 'numeric');
            app.EndFrameButton = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.EndFrameButton.ButtonPushedFcn = createCallbackFcn(app, @EndFrameButtonPushed, true);
            app.EndFrameButton.Text = 'End';
            app.StartFrameButton = uibutton(app.ClassifyBehaviorPanel, 'push');
            app.StartFrameButton.ButtonPushedFcn = createCallbackFcn(app, @StartFrameButtonPushed, true);
            app.StartFrameButton.Text = 'Start';
            app.OtherCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.OtherCheckBox.ValueChangedFcn = createCallbackFcn(app, @OtherCheckBoxChanged, true);
            app.OtherCheckBox.Text = 'Other';
            app.FlightCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.FlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlightCheckBoxChanged, true);
            app.FlightCheckBox.Text = 'Flight';
            app.HLgroomingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.HLgroomingCheckBox.ValueChangedFcn = createCallbackFcn(app, @HLgroomingCheckBoxChanged, true);
            app.HLgroomingCheckBox.Text = 'HL grooming';
            app.FLgroomingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.FLgroomingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FLgroomingCheckBoxChanged, true);
            app.FLgroomingCheckBox.Text = 'FL grooming';
            app.WalkingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.WalkingCheckBox.ValueChangedFcn = createCallbackFcn(app, @WalkingCheckBoxChanged, true);
            app.WalkingCheckBox.Text = 'Walking';
            app.RestingCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.RestingCheckBox.ValueChangedFcn = createCallbackFcn(app, @RestingCheckBoxChanged, true);
            app.RestingCheckBox.Text = 'Resting';
            app.RestingCheckBox.Value = true;
            app.AnalyzeCheckBox = uicheckbox(app.ClassifyBehaviorPanel);
            app.AnalyzeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnalyzeCheckBoxChanged, true);
            app.AnalyzeCheckBox.Text = 'Analyze';
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.KeypointsCheckBox = uicheckbox(app.DisplayPanel);
            app.KeypointsCheckBox.Text = 'Keypoints';
            app.KeypointsCheckBox.Value = true;
            app.AdjustDisplayButton = uibutton(app.DisplayPanel, 'push');
            app.AdjustDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustDisplayButtonPushed, true);
            app.AdjustDisplayButton.Text = 'Adjust';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fly_on_the_ball_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end